classdef HeadDirectionLabeler < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TRIALUIFigure             matlab.ui.Figure
        FileMenu                  matlab.ui.container.Menu
        OpenVideoMenu             matlab.ui.container.Menu
        LoadPreviousDataMenu      matlab.ui.container.Menu
        SaveMenu                  matlab.ui.container.Menu
        ExportFramesMenu          matlab.ui.container.Menu
        OptionMenu                matlab.ui.container.Menu
        ChangePointerMouseMenu    matlab.ui.container.Menu
        ChangePointerCrossMenu    matlab.ui.container.Menu
        ChangePointerHandMenu     matlab.ui.container.Menu
        AboutMenu                 matlab.ui.container.Menu
        AboutMenu_2               matlab.ui.container.Menu
        CurrentFrameSpinnerLabel  matlab.ui.control.Label
        CurrentFrameSpinner       matlab.ui.control.Spinner
        VideoSlider               matlab.ui.control.Slider
        CurrentTime               matlab.ui.control.Label
        StepLabel                 matlab.ui.control.Label
        GoFirstFrame              matlab.ui.control.Button
        GoLastFrame               matlab.ui.control.Button
        ShowInterpDataCheckBox    matlab.ui.control.CheckBox
        DropDown                  matlab.ui.control.DropDown
        RunInterpolationButton    matlab.ui.control.Button
        EstimatedTime             matlab.ui.control.Label
        V13Label                  matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
    end

    properties (Access = private)
        videoreader;
        videoPath; % Description
        frameSize;
        numberofframe;
        currentframe;
        step;
        locationdata;
        start_time;
        DOT_SIZE = 15;
        ARROW_LENGTH = 20;
    end
    methods (Access = private)
        
        function changeCurrentFrame(app,frame)
            app.currentframe = frame;
            
            % Spinner
            app.CurrentFrameSpinner.Value = frame;
            
            % Time Label
            timeInSec = (1 / app.videoreader.FrameRate) * frame;
            time_minute = floor(timeInSec / 60);
            time_second = timeInSec - (time_minute * 60);
            app.CurrentTime.Text = strcat(...
                num2str(time_minute,'%02.0f'),...
                ':',...
                num2str(time_second,'%06.3f')...
                );
            
            % Slider
            app.VideoSlider.Value = frame;
            
            % Duration Bar
            app.EstimatedTime.Text = strcat([...
                num2str(round(app.currentframe / app.numberofframe * 100)),...
                '% | ',...
                datestr(seconds(toc),'HH:MM:SS'),...
                ' | ',...
                num2str(sum(app.locationdata(:,4))/app.numberofframe*100,'%05.2f'),...
                '% labeled']);
            
            % Show the frame
            cla(app.UIAxes);
            imshow(read(app.videoreader, frame),'Parent',app.UIAxes);
            
            if (app.locationdata(app.currentframe,4) ~= 0) % location
                % get new line
                head_idx = app.locationdata(app.currentframe,1:2);
                dot1 = [head_idx(2),app.frameSize(2) - head_idx(1)];
                dot2 = dot1 + app.ARROW_LENGTH * [cos(deg2rad(app.locationdata(app.currentframe,3))), sin(deg2rad(app.locationdata(app.currentframe,3)))];
                hold(app.UIAxes,'on');
                plot(app.UIAxes,[dot1(1), dot2(1)], [dot1(2), dot2(2)],'LineWidth',3,'Color','r');
                plot(app.UIAxes,dot1(1),dot1(2),'MarkerSize',app.DOT_SIZE,'Marker','+','Color','k');
            end
            
            
        end
        function result = inrange(~, number, max_value, min_value)
        % include the edge
            if and(number >= min_value, number <= max_value)
                result = true;
            else
                result = false;
            end
            
        end
        
        function output = getDegree(~,v1,v2, option)
            output = acos((v2(1)-v1(1))/norm(v2-v1));
            if (v1(2)>v2(2))
                output = 2 * pi - output;
            end
            if strcmp(option, 'rad')
                return
            elseif strcmp(option, 'deg')
                output = rem(rad2deg(1*output),360);
                return
            else
                error('wrong input');
            end
                
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            app.SaveMenu.Enable = false;
            app.LoadPreviousDataMenu.Enable = false;
            app.step = 5;
            app.CurrentFrameSpinner.Enable = false;
            app.VideoSlider.Enable = false;
            app.ShowInterpDataCheckBox.Enable = false;
            app.GoFirstFrame.Enable = false;
            app.GoLastFrame.Enable = false;
            app.RunInterpolationButton.Enable = false;
            app.DropDown.Enable = false;
            
            % Refreash First Image
            app.UIAxes.BackgroundColor = 'w';
            axis(app.UIAxes,'image');
            imshow(zeros(app.UIAxes.Position(4),app.UIAxes.Position(3),3),'Parent',app.UIAxes);
            set(app.UIAxes,'YDir','normal')
            disableDefaultInteractivity(app.UIAxes);
        end

        % Menu selected function: OpenVideoMenu
        function OpenVideoButtonPushed(app, event)
            %[filename, path] = uigetfile('*.*',"Select Video to
            %process");%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            path = 'D:\Data\Small Experiment\LARGE\';
            filename = '2021-04-22 11-47-11.mkv';
            if filename ~= 0
                app.videoPath = strcat(path, filename);
                app.videoreader = VideoReader(app.videoPath);
                app.numberofframe = app.videoreader.NumberOfFrames;
                
                % video
                app.videoreader = VideoReader(app.videoPath);
                app.frameSize = [app.videoreader.Width, app.videoreader.Height];
                
                msgbox({...
                    strcat('Video Name : ', filename),...
                    strcat('Frame Rate : ', num2str(app.videoreader.FrameRate)),...
                    strcat('Dimension : ', num2str(app.videoreader.Width), ' x ', num2str(app.videoreader.Height)),...
                    strcat('Duration of one Frame : ', num2str(1/app.videoreader.FrameRate), 'seconds'),...
                    strcat('Number of Frame : ', num2str(app.numberofframe))...
                    });
                
                app.VideoSlider.Limits = [1, app.numberofframe];
                app.VideoSlider.MajorTicks = 1 : app.videoreader.FrameRate * 60 : app.numberofframe;
                app.VideoSlider.MajorTickLabels = cellstr(num2str((0 : numel(app.VideoSlider.MajorTicks))'));
                
                imshow(read(app.videoreader,1),'Parent',app.UIAxes);
                
                app.currentframe = 1;
                
                %% Get the total locationdata size
                app.locationdata = zeros(app.numberofframe,4); % real X, real Y, degree, iseditted
                
                app.SaveMenu.Enable = true;
                app.LoadPreviousDataMenu.Enable = true;
                app.CurrentFrameSpinner.Enable = true;
                app.VideoSlider.Enable = true;
                app.GoFirstFrame.Enable = true;
                app.GoLastFrame.Enable = true;
                app.RunInterpolationButton.Enable = true;
                app.DropDown.Enable = true;
                app.ShowInterpDataCheckBox.Enable = false;
            end    
        end

        % Menu selected function: AboutMenu_2
        function AboutMenu_2Selected(app, event)
            msgbox({'2021 Knowblesse'});
        end

        % Key release function: TRIALUIFigure
        function TRIALUIFigureKeyRelease(app, event)
            key = event.Key;
            switch key
                case 'space'
                    % picture axis (including drawline function and imshow,
                    % has inverted Y axis. (Top - Left Corner
                    line = drawline(app.UIAxes); % Position of the line is x,y (first pick = head); x,y (last pick)
                    head_idx = [app.frameSize(2)-line.Position(1,2), line.Position(1,1)];
                    
                    degree = app.getDegree(line.Position(1,:), line.Position(2,:),'deg');
                    app.locationdata(app.currentframe,1:3) = [head_idx,degree];
                    app.locationdata(app.currentframe,4) = 1;
                    if app.currentframe + app.step > app.numberofframe
                        msgbox('The last step');
                    else
                        app.currentframe = app.currentframe + app.step;
                        app.changeCurrentFrame(app.currentframe);
                    end
                case 'rightarrow'
                    if app.currentframe + app.step > app.numberofframe
                        msgbox('The last step');
                    else
                        app.currentframe = app.currentframe + app.step;
                        app.changeCurrentFrame(app.currentframe);
                    end
                case 'leftarrow'
                    if app.currentframe - app.step < 1 
                        msgbox('The first step');
                    else
                        app.currentframe = app.currentframe - app.step;
                        app.changeCurrentFrame(app.currentframe);
                    end
                case 'uparrow'
                    app.step = app.step + 1;
                    app.StepLabel.Text = strcat('Step =  ', num2str(app.step));
                case 'downarrow'
                    if app.step > 1
                        app.step = app.step - 1;
                        app.StepLabel.Text = strcat('Step =  ', num2str(app.step));
                    end
            end
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
        filename_candidate = strcat(app.videoPath,'_data.csv');
        if exist(filename_candidate,'file') > 0 % numbered filename
            filename_number = 1;
            isfilenamefixed = false;
            filename_candidate = strcat(app.videoPath,'_data(',num2str(filename_number),').csv');
            while ~isfilenamefixed
                if exist(filename_candidate,'file') > 0
                    filename_number = filename_number + 1;
                    filename_candidate = strcat(app.videoPath,'_data(',num2str(filename_number),').csv');
                else
                    filename = filename_candidate;
                    isfilenamefixed = true;
                end    
            end
        else
            filename = filename_candidate;
        end
        
        try
            writematrix(app.locationdata, filename);
            msgbox("File Saved!");
        catch 
            warndlg('Can not write the file');
        end
        
        end

        % Value changed function: CurrentFrameSpinner
        function CurrentFrameSpinnerValueChanged(app, event)
            value = app.CurrentFrameSpinner.Value;
            app.changeCurrentFrame(round(value));
        end

        % Value changed function: VideoSlider
        function VideoSliderValueChanged(app, event)
            value = app.VideoSlider.Value;
            app.changeCurrentFrame(round(value));
        end

        % Menu selected function: LoadPreviousDataMenu
        function LoadPreviousDataMenuSelected(app, event)
            [filename, path] = uigetfile('*.csv',"Select Datafile csv to process");
            if filename ~= 0    
                 rmat = readmatrix(strcat(path,filename));
                 if isequal(size(rmat), [app.numberofframe,4])
                    app.locationdata = rmat;    
                 else
                     errordlg('Data file size mismatch');
                 end
            end
        end

        % Button pushed function: GoFirstFrame
        function GoFirstFrameButtonPushed(app, event)
            app.changeCurrentFrame(1);
        end

        % Button pushed function: GoLastFrame
        function GoLastFrameButtonPushed(app, event)
            app.changeCurrentFrame(app.numberofframe);
        end

        % Callback function: DropDown, RunInterpolationButton
        function RunInterpolationButtonValueChanged(app, event)
            app.interpolateData();
            app.changeCurrentFrame(app.currentframe);
        end

        % Value changed function: ShowInterpDataCheckBox
        function ShowInterpDataCheckBoxValueChanged(app, event)
            value = app.ShowInterpDataCheckBox.Value;
            if value
                app.interpolateData();
                app.changeCurrentFrame(app.currentframe);
            end
        end

        % Menu selected function: ChangePointerMouseMenu
        function ChangePointerMouseMenuSelected(app, event)
            app.TRIALUIFigure.Pointer = 'arrow';
        end

        % Menu selected function: ChangePointerCrossMenu
        function ChangePointerCrossMenuSelected(app, event)
            app.TRIALUIFigure.Pointer = 'cross';
        end

        % Menu selected function: ChangePointerHandMenu
        function ChangePointerHandMenuSelected(app, event)
            app.TRIALUIFigure.Pointer = 'hand';
        end

        % Menu selected function: ExportFramesMenu
        function ExportFramesMenuSelected(app, event)
            for f = 1 : app.numberofframe
                if app.locationdata(f,4) == 1
                    img = read(app.videoreader, f);
                    imwrite(img,strcat(num2str(11,'%06d'),'.png'))
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TRIALUIFigure = uifigure('Visible', 'off');
            app.TRIALUIFigure.Name = 'TRIAL';
            app.TRIALUIFigure.KeyReleaseFcn = createCallbackFcn(app, @TRIALUIFigureKeyRelease, true);
            app.FileMenu = uimenu(app.TRIALUIFigure);
            app.FileMenu.Text = 'File';
            app.OpenVideoMenu = uimenu(app.FileMenu);
            app.OpenVideoMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenVideoButtonPushed, true);
            app.OpenVideoMenu.Text = 'Open Video';
            app.LoadPreviousDataMenu = uimenu(app.FileMenu);
            app.LoadPreviousDataMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadPreviousDataMenuSelected, true);
            app.LoadPreviousDataMenu.Text = 'Load Previous Data';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.ExportFramesMenu = uimenu(app.FileMenu);
            app.ExportFramesMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportFramesMenuSelected, true);
            app.ExportFramesMenu.Text = 'Export Frames';
            app.OptionMenu = uimenu(app.TRIALUIFigure);
            app.OptionMenu.Text = 'Option';
            app.ChangePointerMouseMenu = uimenu(app.OptionMenu);
            app.ChangePointerMouseMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangePointerMouseMenuSelected, true);
            app.ChangePointerMouseMenu.Text = 'Change Pointer : Mouse';
            app.ChangePointerCrossMenu = uimenu(app.OptionMenu);
            app.ChangePointerCrossMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangePointerCrossMenuSelected, true);
            app.ChangePointerCrossMenu.Text = 'Change Pointer : Cross';
            app.ChangePointerHandMenu = uimenu(app.OptionMenu);
            app.ChangePointerHandMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangePointerHandMenuSelected, true);
            app.ChangePointerHandMenu.Text = 'Change Pointer : Hand';
            app.AboutMenu = uimenu(app.TRIALUIFigure);
            app.AboutMenu.Text = 'About';
            app.AboutMenu_2 = uimenu(app.AboutMenu);
            app.AboutMenu_2.MenuSelectedFcn = createCallbackFcn(app, @AboutMenu_2Selected, true);
            app.AboutMenu_2.Text = 'About';
            app.CurrentFrameSpinnerLabel = uilabel(app.TRIALUIFigure);
            app.CurrentFrameSpinnerLabel.Text = 'Current Frame';
            app.CurrentFrameSpinner = uispinner(app.TRIALUIFigure);
            app.CurrentFrameSpinner.ValueDisplayFormat = '%.0f';
            app.CurrentFrameSpinner.ValueChangedFcn = createCallbackFcn(app, @CurrentFrameSpinnerValueChanged, true);
            app.CurrentFrameSpinner.Value = 1;
            app.VideoSlider = uislider(app.TRIALUIFigure);
            app.VideoSlider.Limits = [0 1];
            app.VideoSlider.MajorTicks = [];
            app.VideoSlider.ValueChangedFcn = createCallbackFcn(app, @VideoSliderValueChanged, true);
            app.VideoSlider.MinorTicks = [];
            app.CurrentTime = uilabel(app.TRIALUIFigure);
            app.CurrentTime.Text = 'MM:SS.sss';
            app.StepLabel = uilabel(app.TRIALUIFigure);
            app.StepLabel.Text = 'Step = 5';
            app.GoFirstFrame = uibutton(app.TRIALUIFigure, 'push');
            app.GoFirstFrame.ButtonPushedFcn = createCallbackFcn(app, @GoFirstFrameButtonPushed, true);
            app.GoFirstFrame.Text = '<<';
            app.GoLastFrame = uibutton(app.TRIALUIFigure, 'push');
            app.GoLastFrame.ButtonPushedFcn = createCallbackFcn(app, @GoLastFrameButtonPushed, true);
            app.GoLastFrame.Text = '>>';
            app.ShowInterpDataCheckBox = uicheckbox(app.TRIALUIFigure);
            app.ShowInterpDataCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowInterpDataCheckBoxValueChanged, true);
            app.ShowInterpDataCheckBox.Text = 'Show Interp. Data';
            app.DropDown = uidropdown(app.TRIALUIFigure);
            app.DropDown.Items = {'linear', 'spline', 'pchip'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @RunInterpolationButtonValueChanged, true);
            app.DropDown.Value = 'linear';
            app.RunInterpolationButton = uibutton(app.TRIALUIFigure, 'push');
            app.RunInterpolationButton.ButtonPushedFcn = createCallbackFcn(app, @RunInterpolationButtonValueChanged, true);
            app.RunInterpolationButton.Text = 'Run Interpolation';
            app.EstimatedTime = uilabel(app.TRIALUIFigure);
            app.EstimatedTime.Text = '00% | 00:00:00 | 00.00% labeled';
            app.V13Label = uilabel(app.TRIALUIFigure);
            app.V13Label.Text = 'V1.3';
            app.UIAxes = uiaxes(app.TRIALUIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            % Show the figure after all components are created
            app.TRIALUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HeadDirectionLabeler

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TRIALUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TRIALUIFigure)
        end
    end
end